classdef SAXSReduction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAXSReductionUIFigure           matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        RawDataFileMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        CurrentProfileMenu              matlab.ui.container.Menu
        CurrentallinfotomatfileMenu     matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProfileTab                      matlab.ui.container.Tab
        ProfileUIAxes                   matlab.ui.control.UIAxes
        ProfileXAxisScaleButtonGroup    matlab.ui.container.ButtonGroup
        ProfileXAxisLogButton           matlab.ui.control.RadioButton
        ProfileXAxisLinearButton        matlab.ui.control.RadioButton
        ProfileYAxisScaleButtonGroup    matlab.ui.container.ButtonGroup
        ProfileYAxisLogButton           matlab.ui.control.RadioButton
        ProfileYAxisLinearButton        matlab.ui.control.RadioButton
        XAxisResolutionButtonGroup      matlab.ui.container.ButtonGroup
        XAxisOnePixelResButton          matlab.ui.control.RadioButton
        XAxisPointsResButton            matlab.ui.control.RadioButton
        XAxisPoints                     matlab.ui.control.NumericEditField
        XAxisUnitButtonGroup            matlab.ui.container.ButtonGroup
        qXAxisUnitButton                matlab.ui.control.RadioButton
        tthXAxisUnitButton              matlab.ui.control.RadioButton
        ImageTab                        matlab.ui.container.Tab
        ImageUIAxes                     matlab.ui.control.UIAxes
        ImageIntensityScaleButtonGroup  matlab.ui.container.ButtonGroup
        ImageIntensityLogButton         matlab.ui.control.RadioButton
        ImageIntensityLinearButton      matlab.ui.control.RadioButton
        MaskConfigTab                   matlab.ui.container.Tab
        EffectiveMaskPreviewUIAxes      matlab.ui.control.UIAxes
        CSVMaskUITable                  matlab.ui.control.Table
        MaskControlTabGroup             matlab.ui.container.TabGroup
        CSVMaskControlTab               matlab.ui.container.Tab
        AddCSVMaskButton                matlab.ui.control.Button
        RemoveCSVMaskButton             matlab.ui.control.Button
        MaskIDDropDownLabel             matlab.ui.control.Label
        CSVMaskIDDropDown               matlab.ui.control.DropDown
        ActiveCSVMaskButton             matlab.ui.control.Button
        InvertCSVMaskButton             matlab.ui.control.Button
        EffectiveMaskControlPanel       matlab.ui.container.Panel
        EffectiveMaskNameEditFieldLabel  matlab.ui.control.Label
        EffectiveMaskNameEditField      matlab.ui.control.EditField
        EffectiveMaskIDDropDownLabel    matlab.ui.control.Label
        EffectiveMaskIDDropDown         matlab.ui.control.DropDown
        SaveMaskPreviewButton           matlab.ui.control.Button
        RemoveMaskPreviewButton         matlab.ui.control.Button
        RegionofInterestTab             matlab.ui.container.Tab
        MaskPreviewUIAxes               matlab.ui.control.UIAxes
        MaskGenTabGroup                 matlab.ui.container.TabGroup
        RadiusMaskGenTab                matlab.ui.container.Tab
        RadiusMaskGenUITable            matlab.ui.control.Table
        RectangleMaskGenTab             matlab.ui.container.Tab
        ExportMaskButton                matlab.ui.control.Button
        MaskGenSymmetryButtonGroup      matlab.ui.container.ButtonGroup
        MaskGenNoneSymButton            matlab.ui.control.RadioButton
        MaskGenCenSymButton             matlab.ui.control.RadioButton
        MaskGenXMirrorButton            matlab.ui.control.RadioButton
        MaskGenYMirrorButton            matlab.ui.control.RadioButton
        MaskGenRefreshButton            matlab.ui.control.Button
        ExperimentalConditionsTab       matlab.ui.container.Tab
        ParametersinProcessButtonGroup  matlab.ui.container.ButtonGroup
        DefaultButton                   matlab.ui.control.RadioButton
        UserDefineButton                matlab.ui.control.RadioButton
        ExpCondUITable                  matlab.ui.control.Table
        MultiProfileTab                 matlab.ui.container.Tab
        MultiProfileUIAxes              matlab.ui.control.UIAxes
        MultiProfileXAxisScaleButtonGroup  matlab.ui.container.ButtonGroup
        MultiProfileXAxisLogButton      matlab.ui.control.RadioButton
        MultiProfileXAxisLinearButton   matlab.ui.control.RadioButton
        MultiProfileYAxisScaleButtonGroup  matlab.ui.container.ButtonGroup
        MultiProfileYAxisLogButton      matlab.ui.control.RadioButton
        MultiProfileYAxisLinearButton   matlab.ui.control.RadioButton
        TabGroup2                       matlab.ui.container.TabGroup
        DataStorageTab                  matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        DataStorageControlTab           matlab.ui.container.Tab
        StoreCurrentDataButton          matlab.ui.control.Button
        RemoveStoredDataButton          matlab.ui.control.Button
        PlotSelectStoredDataButton      matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        StoredDataOffsetMagnificationTab  matlab.ui.container.Tab
        OffsetEditFieldLabel            matlab.ui.control.Label
        StoredDataOffsetEditField       matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel     matlab.ui.control.Label
        StoredDataMagnificationEditField  matlab.ui.control.NumericEditField
        DataStorageUITable              matlab.ui.control.Table
        DataIDDropDownLabel             matlab.ui.control.Label
        StoredDataIDDropDown            matlab.ui.control.DropDown
        Tab2_2                          matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DataSheetSelectionTab           matlab.ui.container.Tab
        CurrentDataSheetSpinnerLabel    matlab.ui.control.Label
        CurrentDataSheetSpinner         matlab.ui.control.Spinner
        CurrentDataSheetReDrawButton    matlab.ui.control.Button
        BackgroundConfigurationPanel    matlab.ui.container.Panel
        BackgroundIDDropDownLabel       matlab.ui.control.Label
        BackgroundIDDropDown            matlab.ui.control.DropDown
        BGDrawButton                    matlab.ui.control.Button
        SaveCurrentDataAsBackgroundButton  matlab.ui.control.Button
        SampleTransEditFieldLabel       matlab.ui.control.Label
        SampleTransEditField            matlab.ui.control.NumericEditField
        BufferTransLabel                matlab.ui.control.Label
        BufferTransEditField            matlab.ui.control.NumericEditField
        BGRemoveButton                  matlab.ui.control.Button
        BGSetButton                     matlab.ui.control.Button
        EffectiveMaskSelectionPanel     matlab.ui.container.Panel
        EffectiveMaskSelectionDropDown  matlab.ui.control.DropDown
        AverageDataTab                  matlab.ui.container.Tab
        StartEditFieldLabel             matlab.ui.control.Label
        AvgDataStartSNEditField         matlab.ui.control.NumericEditField
        IncrementEditFieldLabel         matlab.ui.control.Label
        AvgDataIncrementEditField       matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        AvgDataEndSNEditField           matlab.ui.control.NumericEditField
        AvgDataButton                   matlab.ui.control.Button
        SkipDataSheetEditFieldLabel     matlab.ui.control.Label
        AvgDataSkipSNEditField          matlab.ui.control.EditField
        TabGroup4                       matlab.ui.container.TabGroup
        MessageTab                      matlab.ui.container.Tab
        MessageTextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        CurrentData % Save data for ploting
        DataStorage % Data keeped
        MasterInfo % Master infomation
        BGInfo % Store backgrounds
        MaskInfo % mask information
        PlotHandles % Handles for plots
        MaskGen % Mask genarator parameters
        MessageStorage % Message-history storage
        AdditionalInfo % Others information for program
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GeneralFunc.PresetVariables(app);
            GeneralFunc.UpdateCSVMaskTable(app,[]);
            GeneralFunc.UpdateDataStorageTable(app,[]);
            EnvSettingCheck = GeneralFunc.EnvSetting();
            if ~EnvSettingCheck
                closereq
            end
            app.AdditionalInfo.Lasth5Folder = pwd;
            app.AdditionalInfo.LastDataExportFolder = pwd;
            app.AdditionalInfo.LastMaskExportFolder = pwd;
            app.AdditionalInfo.LastMaskImportFolder = pwd;
        end

        % Menu selected function: RawDataFileMenu
        function RawDataFileMenuSelected(app, event)
            UserCallback.EigerMasterImport(app,event);
        end

        % Value changed function: CurrentDataSheetSpinner
        function CurrentDataSheetSpinnerValueChanged(app, event)
            % value = app.CurrentDataSheetSpinner.Value;
            UserCallback.DataSheetSelect(app,event);
        end

        % Selection changed function: ParametersinProcessButtonGroup
        function ParametersinProcessButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ParametersinProcessButtonGroup.SelectedObject;
            GeneralFunc.UpdateMasterInfoFromTable(app,event);
            GeneralFunc.ImageProfileConfig(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Cell edit callback: ExpCondUITable
        function ExpCondUITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            GeneralFunc.UpdateMasterInfoFromTable(app, event);      
        end

        % Callback function: ActiveCSVMaskButton, AddCSVMaskButton, 
        % CSVMaskUITable, InvertCSVMaskButton, RemoveCSVMaskButton
        function AddCSVMaskButtonPushed(app, event)
            UserCallback.CSVMaskControl(app, event);
            % GeneralFunc.DataProcess(app,event);
            % GeneralFunc.PlotCurrentImage(app,event);
            % GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Button pushed function: CurrentDataSheetReDrawButton
        function CurrentDataSheetReDrawButtonPushed(app, event)
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Selection changed function: ProfileXAxisScaleButtonGroup
        function ProfileXAxisScaleButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ProfileXAxisScaleButtonGroup.SelectedObject;
            GeneralFunc.PlotCurrentProfile(app, event);
        end

        % Selection changed function: ProfileYAxisScaleButtonGroup
        function ProfileYAxisScaleButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ProfileYAxisScaleButtonGroup.SelectedObject;
            GeneralFunc.PlotCurrentProfile(app, event);
        end

        % Selection changed function: ImageIntensityScaleButtonGroup
        function ImageIntensityScaleButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ImageIntensityScaleButtonGroup.SelectedObject;
            GeneralFunc.PlotCurrentImage(app, event);
        end

        % Button pushed function: ExportMaskButton
        function ExportMaskButtonPushed(app, event)
            UserCallback.ExportCSVMask(app,event);
        end

        % Cell edit callback: RadiusMaskGenUITable
        function RadiusMaskGenUITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            GeneralFunc.MaskGenerator(app,event);
            GeneralFunc.MaskGenPreview(app,event);
        end

        % Selection changed function: MaskGenSymmetryButtonGroup
        function MaskGenSymmetryButtonGroupSelectionChanged(app, event)
            % selectedButton = app.MaskGenSymmetryButtonGroup.SelectedObject;
            GeneralFunc.MaskGenerator(app,event);
            GeneralFunc.MaskGenPreview(app,event);
        end

        % Button pushed function: MaskGenRefreshButton
        function MaskGenRefreshButtonPushed(app, event)
            GeneralFunc.MaskGenerator(app,event);
            GeneralFunc.MaskGenPreview(app,event);
        end

        % Button pushed function: SaveCurrentDataAsBackgroundButton
        function SaveCurrentDataAsBackgroundButtonPushed(app, event)
            UserCallback.BackgroundControl(app,event);
        end

        % Button pushed function: BGRemoveButton
        function BGRemoveButtonPushed(app, event)
            UserCallback.BackgroundControl(app,event);
        end

        % Button pushed function: BGSetButton
        function BGSetButtonPushed(app, event)
            UserCallback.BackgroundControl(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Button pushed function: BGDrawButton
        function BGDrawButtonPushed(app, event)
            UserCallback.BackgroundControl(app,event);
        end

        % Value changed function: SampleTransEditField
        function SampleTransEditFieldValueChanged(app, event)
            % value = app.SampleTransEditField.Value;
            UserCallback.BackgroundControl(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Value changed function: BufferTransEditField
        function BufferTransEditFieldValueChanged(app, event)
            % value = app.BufferTransEditField.Value;
            UserCallback.BackgroundControl(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Button pushed function: AvgDataButton
        function AvgDataButtonPushed(app, event)
            UserCallback.AverageData(app,event);
        end

        % Selection changed function: XAxisResolutionButtonGroup
        function XAxisResolutionButtonGroupSelectionChanged(app, event)
            % selectedButton = app.XAxisResolutionButtonGroup.SelectedObject;
            GeneralFunc.ImageProfileConfig(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Value changed function: XAxisPoints
        function XAxisPointsValueChanged(app, event)
            % value = app.XAxisPoints.Value;
            GeneralFunc.ImageProfileConfig(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Menu selected function: CurrentProfileMenu
        function CurrentProfileMenuSelected(app, event)
            UserCallback.ExportCurrentProfile(app,event);
        end

        % Selection changed function: XAxisUnitButtonGroup
        function XAxisUnitButtonGroupSelectionChanged(app, event)
            % selectedButton = app.XAxisUnitButtonGroup.SelectedObject;
            GeneralFunc.ImageProfileConfig(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end

        % Button pushed function: LoadDataButton, 
        % PlotSelectStoredDataButton, RemoveStoredDataButton, 
        % StoreCurrentDataButton
        function StoreCurrentDataButtonPushed(app, event)
            UserCallback.DataStorageControl(app,event);
        end

        % Selection changed function: 
        % MultiProfileXAxisScaleButtonGroup
        function MultiProfileXAxisScaleButtonGroupSelectionChanged(app, event)
            % selectedButton = app.MultiProfileXAxisScaleButtonGroup.SelectedObject;
            GeneralFunc.PlotKeepedProfile(app,event);
        end

        % Selection changed function: 
        % MultiProfileYAxisScaleButtonGroup
        function MultiProfileYAxisScaleButtonGroupSelectionChanged(app, event)
            % selectedButton = app.MultiProfileYAxisScaleButtonGroup.SelectedObject;
            GeneralFunc.PlotKeepedProfile(app,event);
        end

        % Value changed function: StoredDataIDDropDown
        function StoredDataIDDropDownValueChanged(app, event)
            % value = app.StoredDataIDDropDown.Value;
            UserCallback.StoredDataSelection(app,event);
        end

        % Menu selected function: CurrentallinfotomatfileMenu
        function CurrentallinfotomatfileMenuSelected(app, event)
            UserCallback.ExportAll2mat(app,event);
        end

        % Value changed function: StoredDataMagnificationEditField, 
        % StoredDataOffsetEditField
        function StoredDataOffsetEditFieldValueChanged(app, event)
            % value = app.StoredDataOffsetEditField.Value;
            UserCallback.StoredDataOffsetMagChanged(app,event);
            GeneralFunc.PlotStoredProfile(app,event);
        end

        % Cell selection callback: DataStorageUITable
        function DataStorageUITableCellSelection(app, event)
            indices = event.Indices;
            app.StoredDataIDDropDown.Value = num2str(indices(1));
            UserCallback.StoredDataSelection(app,event)
        end

        % Cell selection callback: CSVMaskUITable
        function CSVMaskUITableCellSelection(app, event)
            indices = event.Indices;
            app.CSVMaskIDDropDown.Value = num2str(indices(1));
        end

        % Callback function: EffectiveMaskIDDropDown, 
        % RemoveMaskPreviewButton, SaveMaskPreviewButton
        function EffectiveMaskIDDropDownValueChanged(app, event)
            % value = app.EffectiveMaskIDDropDown.Value;
            UserCallback.EffectiveMaskControl(app,event);
        end

        % Value changed function: EffectiveMaskSelectionDropDown
        function EffectiveMaskSelectionDropDownValueChanged(app, event)
            % value = app.EffectiveMaskSelectionDropDown.Value;
            UserCallback.EffectiveMaskSelection(app,event);
            GeneralFunc.DataProcess(app,event);
            GeneralFunc.PlotCurrentImage(app,event);
            GeneralFunc.PlotCurrentProfile(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAXSReductionUIFigure = uifigure('Visible', 'off');
            app.SAXSReductionUIFigure.Name = 'SAXSReduction';
            app.ImportMenu = uimenu(app.SAXSReductionUIFigure);
            app.ImportMenu.Text = 'Import';
            app.RawDataFileMenu = uimenu(app.ImportMenu);
            app.RawDataFileMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataFileMenuSelected, true);
            app.RawDataFileMenu.Text = 'Raw Data File';
            app.ExportMenu = uimenu(app.SAXSReductionUIFigure);
            app.ExportMenu.Text = 'Export';
            app.CurrentProfileMenu = uimenu(app.ExportMenu);
            app.CurrentProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentProfileMenuSelected, true);
            app.CurrentProfileMenu.Text = 'Current Profile';
            app.CurrentallinfotomatfileMenu = uimenu(app.ExportMenu);
            app.CurrentallinfotomatfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentallinfotomatfileMenuSelected, true);
            app.CurrentallinfotomatfileMenu.Text = 'Current all info. to mat file';
            app.TabGroup = uitabgroup(app.SAXSReductionUIFigure);
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.ProfileUIAxes = uiaxes(app.ProfileTab);
            title(app.ProfileUIAxes, 'Title')
            xlabel(app.ProfileUIAxes, 'X')
            ylabel(app.ProfileUIAxes, 'Y')
            app.ProfileXAxisScaleButtonGroup = uibuttongroup(app.ProfileTab);
            app.ProfileXAxisScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProfileXAxisScaleButtonGroupSelectionChanged, true);
            app.ProfileXAxisScaleButtonGroup.Title = 'X Axis Scale';
            app.ProfileXAxisLogButton = uiradiobutton(app.ProfileXAxisScaleButtonGroup);
            app.ProfileXAxisLogButton.Text = 'Log';
            app.ProfileXAxisLogButton.Value = true;
            app.ProfileXAxisLinearButton = uiradiobutton(app.ProfileXAxisScaleButtonGroup);
            app.ProfileXAxisLinearButton.Text = 'Linear';
            app.ProfileYAxisScaleButtonGroup = uibuttongroup(app.ProfileTab);
            app.ProfileYAxisScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProfileYAxisScaleButtonGroupSelectionChanged, true);
            app.ProfileYAxisScaleButtonGroup.Title = 'Y Axis Scale';
            app.ProfileYAxisLogButton = uiradiobutton(app.ProfileYAxisScaleButtonGroup);
            app.ProfileYAxisLogButton.Text = 'Log';
            app.ProfileYAxisLogButton.Value = true;
            app.ProfileYAxisLinearButton = uiradiobutton(app.ProfileYAxisScaleButtonGroup);
            app.ProfileYAxisLinearButton.Text = 'Linear';
            app.XAxisResolutionButtonGroup = uibuttongroup(app.ProfileTab);
            app.XAxisResolutionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisResolutionButtonGroupSelectionChanged, true);
            app.XAxisResolutionButtonGroup.Title = 'X Axis Resolution';
            app.XAxisOnePixelResButton = uiradiobutton(app.XAxisResolutionButtonGroup);
            app.XAxisOnePixelResButton.Text = '1 Pixel Resolution';
            app.XAxisOnePixelResButton.Value = true;
            app.XAxisPointsResButton = uiradiobutton(app.XAxisResolutionButtonGroup);
            app.XAxisPointsResButton.Text = '                      Points';
            app.XAxisPoints = uieditfield(app.XAxisResolutionButtonGroup, 'numeric');
            app.XAxisPoints.Limits = [2 Inf];
            app.XAxisPoints.ValueDisplayFormat = '%d';
            app.XAxisPoints.ValueChangedFcn = createCallbackFcn(app, @XAxisPointsValueChanged, true);
            app.XAxisPoints.Value = 500;
            app.XAxisUnitButtonGroup = uibuttongroup(app.ProfileTab);
            app.XAxisUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisUnitButtonGroupSelectionChanged, true);
            app.XAxisUnitButtonGroup.Title = 'X Axis Unit';
            app.qXAxisUnitButton = uiradiobutton(app.XAxisUnitButtonGroup);
            app.qXAxisUnitButton.Text = 'q (1/A)';
            app.qXAxisUnitButton.Value = true;
            app.tthXAxisUnitButton = uiradiobutton(app.XAxisUnitButtonGroup);
            app.tthXAxisUnitButton.Text = 'tth (deg.)';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageUIAxes = uiaxes(app.ImageTab);
            title(app.ImageUIAxes, 'Title')
            xlabel(app.ImageUIAxes, 'X')
            ylabel(app.ImageUIAxes, 'Y')
            app.ImageIntensityScaleButtonGroup = uibuttongroup(app.ImageTab);
            app.ImageIntensityScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageIntensityScaleButtonGroupSelectionChanged, true);
            app.ImageIntensityScaleButtonGroup.Title = 'Intensity Scale';
            app.ImageIntensityLogButton = uiradiobutton(app.ImageIntensityScaleButtonGroup);
            app.ImageIntensityLogButton.Text = 'Log';
            app.ImageIntensityLogButton.Value = true;
            app.ImageIntensityLinearButton = uiradiobutton(app.ImageIntensityScaleButtonGroup);
            app.ImageIntensityLinearButton.Text = 'Linear';
            app.MaskConfigTab = uitab(app.TabGroup);
            app.MaskConfigTab.Title = 'Mask Config';
            app.EffectiveMaskPreviewUIAxes = uiaxes(app.MaskConfigTab);
            title(app.EffectiveMaskPreviewUIAxes, 'Title')
            xlabel(app.EffectiveMaskPreviewUIAxes, 'X')
            ylabel(app.EffectiveMaskPreviewUIAxes, 'Y')
            app.CSVMaskUITable = uitable(app.MaskConfigTab);
            app.CSVMaskUITable.ColumnName = {'Name'; 'Active'; 'Inverse'};
            app.CSVMaskUITable.ColumnEditable = [false true true];
            app.CSVMaskUITable.CellEditCallback = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.CSVMaskUITable.CellSelectionCallback = createCallbackFcn(app, @CSVMaskUITableCellSelection, true);
            app.MaskControlTabGroup = uitabgroup(app.MaskConfigTab);
            app.CSVMaskControlTab = uitab(app.MaskControlTabGroup);
            app.CSVMaskControlTab.Title = 'CSV Mask Control';
            app.AddCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.AddCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.AddCSVMaskButton.Text = 'Add';
            app.RemoveCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.RemoveCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.RemoveCSVMaskButton.Text = 'Remove';
            app.MaskIDDropDownLabel = uilabel(app.CSVMaskControlTab);
            app.MaskIDDropDownLabel.Text = 'Mask ID';
            app.CSVMaskIDDropDown = uidropdown(app.CSVMaskControlTab);
            app.CSVMaskIDDropDown.Items = {'1.', '2.', '3.', '4.', '5.', '6.', '7.', '8.', '9.', '10.'};
            app.CSVMaskIDDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.CSVMaskIDDropDown.Value = '1';
            app.ActiveCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.ActiveCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.ActiveCSVMaskButton.Text = 'Active / Inactive';
            app.InvertCSVMaskButton = uibutton(app.CSVMaskControlTab, 'push');
            app.InvertCSVMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddCSVMaskButtonPushed, true);
            app.InvertCSVMaskButton.Text = 'Invert / Normal';
            app.EffectiveMaskControlPanel = uipanel(app.MaskConfigTab);
            app.EffectiveMaskControlPanel.Title = 'Effective Mask Control';
            app.EffectiveMaskNameEditFieldLabel = uilabel(app.EffectiveMaskControlPanel);
            app.EffectiveMaskNameEditFieldLabel.Text = 'Effective Mask Name';
            app.EffectiveMaskNameEditField = uieditfield(app.EffectiveMaskControlPanel, 'text');
            app.EffectiveMaskIDDropDownLabel = uilabel(app.EffectiveMaskControlPanel);
            app.EffectiveMaskIDDropDownLabel.Text = 'Effective Mask ID';
            app.EffectiveMaskIDDropDown = uidropdown(app.EffectiveMaskControlPanel);
            app.EffectiveMaskIDDropDown.Items = {'1.', '2.', '3.', '4.', '5.', '6.', '7.', '8.', '9.', '10.'};
            app.EffectiveMaskIDDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.EffectiveMaskIDDropDown.ValueChangedFcn = createCallbackFcn(app, @EffectiveMaskIDDropDownValueChanged, true);
            app.EffectiveMaskIDDropDown.Value = '1';
            app.SaveMaskPreviewButton = uibutton(app.EffectiveMaskControlPanel, 'push');
            app.SaveMaskPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @EffectiveMaskIDDropDownValueChanged, true);
            app.SaveMaskPreviewButton.Text = 'Save Mask Preview';
            app.RemoveMaskPreviewButton = uibutton(app.EffectiveMaskControlPanel, 'push');
            app.RemoveMaskPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @EffectiveMaskIDDropDownValueChanged, true);
            app.RemoveMaskPreviewButton.Text = 'Remove';
            app.RegionofInterestTab = uitab(app.TabGroup);
            app.RegionofInterestTab.Title = 'Region of Interest';
            app.MaskPreviewUIAxes = uiaxes(app.RegionofInterestTab);
            title(app.MaskPreviewUIAxes, 'Title')
            xlabel(app.MaskPreviewUIAxes, 'X')
            ylabel(app.MaskPreviewUIAxes, 'Y')
            app.MaskGenTabGroup = uitabgroup(app.RegionofInterestTab);
            app.RadiusMaskGenTab = uitab(app.MaskGenTabGroup);
            app.RadiusMaskGenTab.Title = 'Radius';
            app.RadiusMaskGenUITable = uitable(app.RadiusMaskGenTab);
            app.RadiusMaskGenUITable.ColumnName = {'Parameter'; 'Value'};
            app.RadiusMaskGenUITable.RowName = {};
            app.RadiusMaskGenUITable.ColumnEditable = [false true];
            app.RadiusMaskGenUITable.CellEditCallback = createCallbackFcn(app, @RadiusMaskGenUITableCellEdit, true);
            app.RectangleMaskGenTab = uitab(app.MaskGenTabGroup);
            app.RectangleMaskGenTab.Title = 'Rectangle';
            app.ExportMaskButton = uibutton(app.RegionofInterestTab, 'push');
            app.ExportMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMaskButtonPushed, true);
            app.ExportMaskButton.Text = 'Export Mask';
            app.MaskGenSymmetryButtonGroup = uibuttongroup(app.RegionofInterestTab);
            app.MaskGenSymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskGenSymmetryButtonGroupSelectionChanged, true);
            app.MaskGenSymmetryButtonGroup.Title = 'Symmetry';
            app.MaskGenNoneSymButton = uiradiobutton(app.MaskGenSymmetryButtonGroup);
            app.MaskGenNoneSymButton.Text = 'None';
            app.MaskGenNoneSymButton.Value = true;
            app.MaskGenCenSymButton = uiradiobutton(app.MaskGenSymmetryButtonGroup);
            app.MaskGenCenSymButton.Text = 'Central Symmetry';
            app.MaskGenXMirrorButton = uiradiobutton(app.MaskGenSymmetryButtonGroup);
            app.MaskGenXMirrorButton.Text = 'X axis Mirror';
            app.MaskGenYMirrorButton = uiradiobutton(app.MaskGenSymmetryButtonGroup);
            app.MaskGenYMirrorButton.Text = 'Y axis Mirror';
            app.MaskGenRefreshButton = uibutton(app.RegionofInterestTab, 'push');
            app.MaskGenRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @MaskGenRefreshButtonPushed, true);
            app.MaskGenRefreshButton.Text = 'Refresh';
            app.ExperimentalConditionsTab = uitab(app.TabGroup);
            app.ExperimentalConditionsTab.Title = 'Experimental Conditions';
            app.ParametersinProcessButtonGroup = uibuttongroup(app.ExperimentalConditionsTab);
            app.ParametersinProcessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersinProcessButtonGroupSelectionChanged, true);
            app.ParametersinProcessButtonGroup.Title = 'Parameters in Process';
            app.DefaultButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.UserDefineButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.UserDefineButton.Text = 'User Define';
            app.ExpCondUITable = uitable(app.ExperimentalConditionsTab);
            app.ExpCondUITable.ColumnName = {'Parameter'; 'Current Setting'; 'Default'; 'User Define'};
            app.ExpCondUITable.RowName = {};
            app.ExpCondUITable.ColumnEditable = [false false false true];
            app.ExpCondUITable.CellEditCallback = createCallbackFcn(app, @ExpCondUITableCellEdit, true);
            app.MultiProfileTab = uitab(app.TabGroup);
            app.MultiProfileTab.Title = 'Multi-Profile';
            app.MultiProfileUIAxes = uiaxes(app.MultiProfileTab);
            title(app.MultiProfileUIAxes, 'Title')
            xlabel(app.MultiProfileUIAxes, 'X')
            ylabel(app.MultiProfileUIAxes, 'Y')
            app.MultiProfileXAxisScaleButtonGroup = uibuttongroup(app.MultiProfileTab);
            app.MultiProfileXAxisScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultiProfileXAxisScaleButtonGroupSelectionChanged, true);
            app.MultiProfileXAxisScaleButtonGroup.Title = 'X Axis Scale';
            app.MultiProfileXAxisLogButton = uiradiobutton(app.MultiProfileXAxisScaleButtonGroup);
            app.MultiProfileXAxisLogButton.Text = 'Log';
            app.MultiProfileXAxisLogButton.Value = true;
            app.MultiProfileXAxisLinearButton = uiradiobutton(app.MultiProfileXAxisScaleButtonGroup);
            app.MultiProfileXAxisLinearButton.Text = 'Linear';
            app.MultiProfileYAxisScaleButtonGroup = uibuttongroup(app.MultiProfileTab);
            app.MultiProfileYAxisScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultiProfileYAxisScaleButtonGroupSelectionChanged, true);
            app.MultiProfileYAxisScaleButtonGroup.Title = 'Y Axis Scale';
            app.MultiProfileYAxisLogButton = uiradiobutton(app.MultiProfileYAxisScaleButtonGroup);
            app.MultiProfileYAxisLogButton.Text = 'Log';
            app.MultiProfileYAxisLogButton.Value = true;
            app.MultiProfileYAxisLinearButton = uiradiobutton(app.MultiProfileYAxisScaleButtonGroup);
            app.MultiProfileYAxisLinearButton.Text = 'Linear';
            app.TabGroup2 = uitabgroup(app.SAXSReductionUIFigure);
            app.DataStorageTab = uitab(app.TabGroup2);
            app.DataStorageTab.Title = 'Data Storage';
            app.TabGroup5 = uitabgroup(app.DataStorageTab);
            app.DataStorageControlTab = uitab(app.TabGroup5);
            app.DataStorageControlTab.Title = 'Data Storage Control';
            app.StoreCurrentDataButton = uibutton(app.DataStorageControlTab, 'push');
            app.StoreCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreCurrentDataButtonPushed, true);
            app.StoreCurrentDataButton.Text = 'Store Current Data';
            app.RemoveStoredDataButton = uibutton(app.DataStorageControlTab, 'push');
            app.RemoveStoredDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreCurrentDataButtonPushed, true);
            app.RemoveStoredDataButton.Text = 'Remove';
            app.PlotSelectStoredDataButton = uibutton(app.DataStorageControlTab, 'push');
            app.PlotSelectStoredDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreCurrentDataButtonPushed, true);
            app.PlotSelectStoredDataButton.Text = 'Plot Select';
            app.LoadDataButton = uibutton(app.DataStorageControlTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreCurrentDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StoredDataOffsetMagnificationTab = uitab(app.TabGroup5);
            app.StoredDataOffsetMagnificationTab.Title = 'Offset/Magnification';
            app.OffsetEditFieldLabel = uilabel(app.StoredDataOffsetMagnificationTab);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.StoredDataOffsetEditField = uieditfield(app.StoredDataOffsetMagnificationTab, 'numeric');
            app.StoredDataOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @StoredDataOffsetEditFieldValueChanged, true);
            app.MagnificationEditFieldLabel = uilabel(app.StoredDataOffsetMagnificationTab);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.StoredDataMagnificationEditField = uieditfield(app.StoredDataOffsetMagnificationTab, 'numeric');
            app.StoredDataMagnificationEditField.ValueChangedFcn = createCallbackFcn(app, @StoredDataOffsetEditFieldValueChanged, true);
            app.StoredDataMagnificationEditField.Value = 1;
            app.DataStorageUITable = uitable(app.DataStorageTab);
            app.DataStorageUITable.ColumnName = {'Select'; 'Name'};
            app.DataStorageUITable.ColumnEditable = [true false];
            app.DataStorageUITable.CellSelectionCallback = createCallbackFcn(app, @DataStorageUITableCellSelection, true);
            app.DataIDDropDownLabel = uilabel(app.DataStorageTab);
            app.DataIDDropDownLabel.Text = 'Data ID';
            app.StoredDataIDDropDown = uidropdown(app.DataStorageTab);
            app.StoredDataIDDropDown.Items = {'1.', '2.', '3.', '4.', '5.', '6.', '7.', '8.', '9.', '10.', '11.', '12.', '13.', '14.', '15.', '16.', '17.', '18.', '19.', '20.'};
            app.StoredDataIDDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.StoredDataIDDropDown.ValueChangedFcn = createCallbackFcn(app, @StoredDataIDDropDownValueChanged, true);
            app.StoredDataIDDropDown.Value = '1';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.TabGroup3 = uitabgroup(app.SAXSReductionUIFigure);
            app.DataSheetSelectionTab = uitab(app.TabGroup3);
            app.DataSheetSelectionTab.Title = 'Data Sheet Selection';
            app.CurrentDataSheetSpinnerLabel = uilabel(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinnerLabel.Text = 'Current Data Sheet';
            app.CurrentDataSheetSpinner = uispinner(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentDataSheetSpinnerValueChanged, true);
            app.CurrentDataSheetReDrawButton = uibutton(app.DataSheetSelectionTab, 'push');
            app.CurrentDataSheetReDrawButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDataSheetReDrawButtonPushed, true);
            app.CurrentDataSheetReDrawButton.Text = 'Redraw Current Data';
            app.BackgroundConfigurationPanel = uipanel(app.DataSheetSelectionTab);
            app.BackgroundConfigurationPanel.Title = 'Background Configuration';
            app.BackgroundIDDropDownLabel = uilabel(app.BackgroundConfigurationPanel);
            app.BackgroundIDDropDownLabel.Text = 'Background ID';
            app.BackgroundIDDropDown = uidropdown(app.BackgroundConfigurationPanel);
            app.BackgroundIDDropDown.Items = {'1.', '2.', '3.', '4.', '5.', '6.', '7.', '8.', '9.', '10.'};
            app.BackgroundIDDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.BackgroundIDDropDown.Value = '1';
            app.BGDrawButton = uibutton(app.BackgroundConfigurationPanel, 'push');
            app.BGDrawButton.ButtonPushedFcn = createCallbackFcn(app, @BGDrawButtonPushed, true);
            app.BGDrawButton.Text = 'Draw';
            app.SaveCurrentDataAsBackgroundButton = uibutton(app.BackgroundConfigurationPanel, 'push');
            app.SaveCurrentDataAsBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataAsBackgroundButtonPushed, true);
            app.SaveCurrentDataAsBackgroundButton.Text = 'Save Current Data As Background';
            app.SampleTransEditFieldLabel = uilabel(app.BackgroundConfigurationPanel);
            app.SampleTransEditFieldLabel.Text = 'Sample Trans.';
            app.SampleTransEditField = uieditfield(app.BackgroundConfigurationPanel, 'numeric');
            app.SampleTransEditField.ValueChangedFcn = createCallbackFcn(app, @SampleTransEditFieldValueChanged, true);
            app.SampleTransEditField.Value = 1;
            app.BufferTransLabel = uilabel(app.BackgroundConfigurationPanel);
            app.BufferTransLabel.Text = 'Buffer Trans.';
            app.BufferTransEditField = uieditfield(app.BackgroundConfigurationPanel, 'numeric');
            app.BufferTransEditField.ValueChangedFcn = createCallbackFcn(app, @BufferTransEditFieldValueChanged, true);
            app.BufferTransEditField.Value = 1;
            app.BGRemoveButton = uibutton(app.BackgroundConfigurationPanel, 'push');
            app.BGRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @BGRemoveButtonPushed, true);
            app.BGRemoveButton.Text = 'Remove';
            app.BGSetButton = uibutton(app.BackgroundConfigurationPanel, 'push');
            app.BGSetButton.ButtonPushedFcn = createCallbackFcn(app, @BGSetButtonPushed, true);
            app.BGSetButton.Text = 'Set';
            app.EffectiveMaskSelectionPanel = uipanel(app.DataSheetSelectionTab);
            app.EffectiveMaskSelectionPanel.Title = 'Effective Mask Selection';
            app.EffectiveMaskSelectionDropDown = uidropdown(app.EffectiveMaskSelectionPanel);
            app.EffectiveMaskSelectionDropDown.Items = {'None'};
            app.EffectiveMaskSelectionDropDown.ItemsData = {'0'};
            app.EffectiveMaskSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EffectiveMaskSelectionDropDownValueChanged, true);
            app.EffectiveMaskSelectionDropDown.Value = '0';
            app.AverageDataTab = uitab(app.TabGroup3);
            app.AverageDataTab.Title = 'Average Data';
            app.StartEditFieldLabel = uilabel(app.AverageDataTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.AvgDataStartSNEditField = uieditfield(app.AverageDataTab, 'numeric');
            app.AvgDataStartSNEditField.RoundFractionalValues = 'on';
            app.AvgDataStartSNEditField.ValueDisplayFormat = '%.0f';
            app.IncrementEditFieldLabel = uilabel(app.AverageDataTab);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.AvgDataIncrementEditField = uieditfield(app.AverageDataTab, 'numeric');
            app.AvgDataIncrementEditField.RoundFractionalValues = 'on';
            app.AvgDataIncrementEditField.ValueDisplayFormat = '%.0f';
            app.EndEditFieldLabel = uilabel(app.AverageDataTab);
            app.EndEditFieldLabel.Text = 'End';
            app.AvgDataEndSNEditField = uieditfield(app.AverageDataTab, 'numeric');
            app.AvgDataEndSNEditField.RoundFractionalValues = 'on';
            app.AvgDataEndSNEditField.ValueDisplayFormat = '%.0f';
            app.AvgDataButton = uibutton(app.AverageDataTab, 'push');
            app.AvgDataButton.ButtonPushedFcn = createCallbackFcn(app, @AvgDataButtonPushed, true);
            app.AvgDataButton.Text = 'Average';
            app.SkipDataSheetEditFieldLabel = uilabel(app.AverageDataTab);
            app.SkipDataSheetEditFieldLabel.Text = 'Skip Data Sheet';
            app.AvgDataSkipSNEditField = uieditfield(app.AverageDataTab, 'text');
            app.TabGroup4 = uitabgroup(app.SAXSReductionUIFigure);
            app.MessageTab = uitab(app.TabGroup4);
            app.MessageTab.Title = 'Message';
            app.MessageTextArea = uitextarea(app.MessageTab);
            app.MessageTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.SAXSReductionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAXSReduction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAXSReductionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAXSReductionUIFigure)
        end
    end
end