classdef SAXSRedundancy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAXSRedundancyUIFigure          matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        EigerMasterMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProfileTab                      matlab.ui.container.Tab
        ImageTab                        matlab.ui.container.Tab
        ImageUIAxes                     matlab.ui.control.UIAxes
        MaskControlTab                  matlab.ui.container.Tab
        MaskPreviewUIAxes               matlab.ui.control.UIAxes
        ExperimentalConditionsTab       matlab.ui.container.Tab
        ParametersinProcessButtonGroup  matlab.ui.container.ButtonGroup
        DefaultButton                   matlab.ui.control.RadioButton
        UserDefineButton                matlab.ui.control.RadioButton
        ExpCondUITable                  matlab.ui.control.Table
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        Tab2_2                          matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DataSheetSelectionTab           matlab.ui.container.Tab
        CurrentDataSheetSpinnerLabel    matlab.ui.control.Label
        CurrentDataSheetSpinner         matlab.ui.control.Spinner
        AverageDataTab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        CurrentData % Save data for ploting
        PlotHandles % Handles for plots
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GeneralFunc.PresetVariables(app);
        end

        % Menu selected function: EigerMasterMenu
        function EigerMasterMenuSelected(app, event)
            UserCallback.EigerMasterImport(app,event);
        end

        % Value changed function: CurrentDataSheetSpinner
        function CurrentDataSheetSpinnerValueChanged(app, event)
            % value = app.CurrentDataSheetSpinner.Value;
            UserCallback.DataSheetSelect(app,event);
        end

        % Selection changed function: ParametersinProcessButtonGroup
        function ParametersinProcessButtonGroupSelectionChanged(app, event)
            % selectedButton = app.ParametersinProcessButtonGroup.SelectedObject;
            GeneralFunc.UpdateMasterInfoFromTable(app,event);
        end

        % Cell edit callback: ExpCondUITable
        function ExpCondUITableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            GeneralFunc.UpdateMasterInfoFromTable(app, event);      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAXSRedundancyUIFigure = uifigure('Visible', 'off');
            app.SAXSRedundancyUIFigure.Name = 'SAXSRedundancy';
            app.ImportMenu = uimenu(app.SAXSRedundancyUIFigure);
            app.ImportMenu.Text = 'Import';
            app.EigerMasterMenu = uimenu(app.ImportMenu);
            app.EigerMasterMenu.MenuSelectedFcn = createCallbackFcn(app, @EigerMasterMenuSelected, true);
            app.EigerMasterMenu.Text = 'Eiger Master';
            app.ExportMenu = uimenu(app.SAXSRedundancyUIFigure);
            app.ExportMenu.Text = 'Export';
            app.TabGroup = uitabgroup(app.SAXSRedundancyUIFigure);
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageUIAxes = uiaxes(app.ImageTab);
            title(app.ImageUIAxes, 'Title')
            xlabel(app.ImageUIAxes, 'X')
            ylabel(app.ImageUIAxes, 'Y')
            app.MaskControlTab = uitab(app.TabGroup);
            app.MaskControlTab.Title = 'Mask Control';
            app.MaskPreviewUIAxes = uiaxes(app.MaskControlTab);
            title(app.MaskPreviewUIAxes, 'Title')
            xlabel(app.MaskPreviewUIAxes, 'X')
            ylabel(app.MaskPreviewUIAxes, 'Y')
            app.ExperimentalConditionsTab = uitab(app.TabGroup);
            app.ExperimentalConditionsTab.Title = 'Experimental Conditions';
            app.ParametersinProcessButtonGroup = uibuttongroup(app.ExperimentalConditionsTab);
            app.ParametersinProcessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersinProcessButtonGroupSelectionChanged, true);
            app.ParametersinProcessButtonGroup.Title = 'Parameters in Process';
            app.DefaultButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.UserDefineButton = uiradiobutton(app.ParametersinProcessButtonGroup);
            app.UserDefineButton.Text = 'User Define';
            app.ExpCondUITable = uitable(app.ExperimentalConditionsTab);
            app.ExpCondUITable.ColumnName = {'Parameter'; 'Current Setting'; 'Default'; 'User Define'};
            app.ExpCondUITable.RowName = {};
            app.ExpCondUITable.ColumnEditable = [false false false true];
            app.ExpCondUITable.CellEditCallback = createCallbackFcn(app, @ExpCondUITableCellEdit, true);
            app.TabGroup2 = uitabgroup(app.SAXSRedundancyUIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.TabGroup3 = uitabgroup(app.SAXSRedundancyUIFigure);
            app.DataSheetSelectionTab = uitab(app.TabGroup3);
            app.DataSheetSelectionTab.Title = 'Data Sheet Selection';
            app.CurrentDataSheetSpinnerLabel = uilabel(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinnerLabel.Text = 'Current Data Sheet';
            app.CurrentDataSheetSpinner = uispinner(app.DataSheetSelectionTab);
            app.CurrentDataSheetSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentDataSheetSpinnerValueChanged, true);
            app.AverageDataTab = uitab(app.TabGroup3);
            app.AverageDataTab.Title = 'Average Data';
            % Show the figure after all components are created
            app.SAXSRedundancyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAXSRedundancy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAXSRedundancyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAXSRedundancyUIFigure)
        end
    end
end